classdef MyCovid19App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Hyperlink                   matlab.ui.control.Hyperlink
        averagenumberofdaysSpinner  matlab.ui.control.Spinner
        averagenumberofdaysSpinnerLabel  matlab.ui.control.Label
        OptionsButtonGroup          matlab.ui.container.ButtonGroup
        DailyButton                 matlab.ui.control.RadioButton
        CumulativeButton            matlab.ui.control.RadioButton
        DatatoplotButtonGroup       matlab.ui.container.ButtonGroup
        BothButton                  matlab.ui.control.RadioButton
        DeathsButton                matlab.ui.control.RadioButton
        CasesButton                 matlab.ui.control.RadioButton
        CountryListBox              matlab.ui.control.ListBox
        CountryLabel                matlab.ui.control.Label
        StateorRegionListBox        matlab.ui.control.ListBox
        StateorRegionLabel          matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties
        GlobalData
        Dates
        StartDate                 % Start date for plotting
        EndDate                   % End date for plotting
        ListOfCountries
        ListOfCountryNames
        NumberOfCountries
        NumberOfStates
        StateMap
        GlobalCumulativeCases
        GlobalCumulativeDeaths
        GlobalDailyCases
        GlobalDailyDeaths
        AverageCumulativeCases    % moving average of cumulative cases
        AverageCumulativeDeaths   % moving average of cumulative deaths
        AverageDailyCases         % moving average of daily cases
        AverageDailyDeaths        % moving average of daily deaths
        TimePoints
        AverageOfDay
        CountryorStateorRegionToFind
        CountrySelected
        StateSelected
        ShowCases
        ShowDeaths
        ShowBoth
        ShowCumulative
        ShowDaily
    end
    
    methods (Access = private)
        function ComputeAverages(app)
            if (app.AverageOfDay > 1)
                if isequal(app.CountrySelected, 'Global')
                    app.AverageDailyCases = movmean(app.GlobalDailyCases, app.AverageOfDay);
                    app.AverageDailyDeaths = movmean(app.GlobalDailyDeaths, app.AverageOfDay);
                    app.AverageCumulativeCases = movmean(app.GlobalCumulativeCases, app.AverageOfDay);
                    app.AverageCumulativeDeaths = movmean(app.GlobalCumulativeDeaths, app.AverageOfDay);
                else
                    IndexOfCurrentCountryExcludingGlobal = findobj([app.ListOfCountries{:}], 'Name', app.CountrySelected).IndexOfCountryExcludingGlobal;
                    if isequal(app.StateSelected, 'All')
                        Country = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal};
                        app.AverageDailyCases = movmean(Country.DailyCases, app.AverageOfDay);
                        app.AverageDailyDeaths = movmean(Country.DailyDeaths, app.AverageOfDay);
                        app.AverageCumulativeCases = movmean(Country.CumulativeCases, app.AverageOfDay);
                        app.AverageCumulativeDeaths = movmean(Country.CumulativeDeaths, app.AverageOfDay);
                    else
                        [~,index] = ismember(app.StateSelected, app.StateorRegionListBox.Items);
                        State = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal}.ListOfStates{index-1};
                        app.AverageDailyCases = movmean(State.DailyCases, app.AverageOfDay);
                        app.AverageDailyDeaths = movmean(State.DailyDeaths, app.AverageOfDay);
                        app.AverageCumulativeCases = movmean(State.CumulativeCases, app.AverageOfDay);
                        app.AverageCumulativeDeaths = movmean(State.CumulativeDeaths, app.AverageOfDay);
                    end
                end
            else
                if isequal(app.CountrySelected, 'Global')
                    app.AverageDailyCases = app.GlobalDailyCases;
                    app.AverageDailyDeaths = app.GlobalDailyDeaths;
                    app.AverageCumulativeCases = app.GlobalCumulativeCases;
                    app.AverageCumulativeDeaths = app.GlobalCumulativeDeaths;
                else
                    IndexOfCurrentCountryExcludingGlobal = findobj([app.ListOfCountries{:}], 'Name', app.CountrySelected).IndexOfCountryExcludingGlobal;
                    if isequal(app.StateSelected, 'All')
                        Country = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal};
                        app.AverageDailyCases = Country.DailyCases;
                        app.AverageDailyDeaths = Country.DailyDeaths;
                        app.AverageCumulativeCases = Country.CumulativeCases;
                        app.AverageCumulativeDeaths = Country.CumulativeDeaths;
                    else
                        [~,index] = ismember(app.StateSelected, app.StateorRegionListBox.Items);
                        State = app.ListOfCountries{IndexOfCurrentCountryExcludingGlobal}.ListOfStates{index-1};
                        app.AverageDailyCases = State.DailyCases;
                        app.AverageDailyDeaths = State.DailyDeaths;
                        app.AverageCumulativeCases = State.CumulativeCases;
                        app.AverageCumulativeDeaths = State.CumulativeDeaths;
                    end
                end
            end
        end
        function title = MakeTitle(app)
            if app.ShowCumulative
                ChartFrequency = 'Cumulative';
            elseif app.ShowDaily
                ChartFrequency = 'Daily';
            end
            
            if (app.ShowCases && ~app.ShowDeaths)
                ChartDataType = 'Cases';
            elseif (app.ShowDeaths && ~app.ShowCases)
                ChartDataType = 'Deaths';
            else
                ChartDataType = 'Cases and Deaths';
            end
            
            if isequal(app.CountrySelected, 'Global')
                ChartName = 'Globally';
            elseif isequal(app.StateSelected, 'All')
                ChartName = [ 'in ', app.CountrySelected ];
            else
                ChartName = [ 'in ', app.StateSelected ];
            end

            if (app.AverageOfDay > 1)
                ChartDaysMean = [ ' (', num2str(app.AverageOfDay), '-day mean)' ];
            else
                ChartDaysMean = '';
            end

            title = [ ChartFrequency, ' Number of ', ChartDataType, ' ', ChartName, ChartDaysMean ];               
        end
        function SetDefault(app)


            app.CountryListBox.Items = [ {'Global'}; app.ListOfCountryNames ];
            app.CountryListBox.Value = 'Global';
            scroll(app.CountryListBox, 'top');
            app.StateorRegionListBox.Items = {'All'};
            app.StateorRegionListBox.Value = 'All';
            app.CumulativeButton.Value = true;
            app.CasesButton.Value = true;
            app.DailyButton.Value = false;
            app.DeathsButton.Value = false;
            app.BothButton.Value = true;

            app.averagenumberofdaysSpinner.Value = 1;


            % Set my properties
            app.CountrySelected = app.CountryListBox.Value;
            app.StateSelected = app.StateorRegionListBox.Value;
            app.AverageOfDay = app.averagenumberofdaysSpinner.Value;
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.ShowBoth = app.BothButton.Value;
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            
            app.PlotData();
        end
        function LabelDates(app)
            app.Dates = app.GlobalData(1,3:end);
            app.StartDate = datenum(app.Dates{1}, 'mm/dd/yy');
            app.EndDate = datenum(app.Dates{end}, 'mm/dd/yy');
            app.TimePoints = linspace(app.StartDate , app.EndDate, length(app.Dates));
        end
        function GenerateData(app)
            app.ListOfCountryNames = unique(app.GlobalData(2:end,1));
            app.NumberOfCountries = length(app.ListOfCountryNames);
            app.NumberOfStates = app.NumberOfCountries - sum(cellfun('isempty', app.GlobalData(2:end,2)));
            keySet = cell(1,app.NumberOfStates);
            valueSet = cell(1,app.NumberOfStates);
            index = 1;
            for ii = 1 : app.NumberOfCountries
                nameOfCountry = app.ListOfCountryNames{ii};
                [~,indexOfCountryIncludingGlobal] = ismember(nameOfCountry, app.GlobalData(:,1));
                app.ListOfCountries{ii} = CovidCountryData(app.GlobalData, nameOfCountry, indexOfCountryIncludingGlobal);
                if ~isempty(app.ListOfCountries{ii}.ListOfStates)
                    for jj = 1 : length(app.ListOfCountries{ii}.ListOfStates)
                        keySet{index} = app.ListOfCountries{ii}.ListOfStates{jj}.Name;
                        valueSet{index} = app.ListOfCountries{ii}.Name;
                        index = index + 1;
                    end
                end
            end
            app.StateMap = containers.Map(keySet, valueSet);
            
            app.GlobalCumulativeCases = zeros(1,length(app.Dates));   % pre-allocation
            for i = 1 : app.NumberOfCountries
                app.GlobalCumulativeCases = app.GlobalCumulativeCases + app.ListOfCountries{i}.CumulativeCases;
            end
            app.GlobalCumulativeDeaths = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalCumulativeDeaths = app.GlobalCumulativeDeaths + app.ListOfCountries{i}.CumulativeDeaths;
            end
            app.GlobalDailyCases = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalDailyCases = app.GlobalDailyCases + app.ListOfCountries{i}.DailyCases;
            end
            app.GlobalDailyDeaths = zeros(1,length(app.Dates));
            for i = 1 : app.NumberOfCountries
                app.GlobalDailyDeaths = app.GlobalDailyDeaths + app.ListOfCountries{i}.DailyDeaths;
            end
        end
        function PlotData(app)
            cla(app.UIAxes, 'reset');
            app.ComputeAverages();
            if ((app.ShowCases && ~app.ShowDeaths) && app.ShowCumulative)
                bar(app.UIAxes, app.TimePoints, app.AverageCumulativeCases, 'FaceColor', '#0072BD');
                xlabel(app.UIAxes, 'Dates');
                ylabel(app.UIAxes, 'Number of Cases');
            elseif ((app.ShowCases && ~app.ShowDeaths) && app.ShowDaily)
                bar(app.UIAxes, app.TimePoints, app.AverageDailyCases, 'FaceColor', '#000000');
                ylabel(app.UIAxes, 'Number of Cases');
            elseif ((app.ShowDeaths && ~app.ShowCases) && app.ShowCumulative)
                plot(app.UIAxes, app.TimePoints, app.AverageCumulativeDeaths, 'Color', 'red', 'LineWidth', 2.5);
                ylabel(app.UIAxes, 'Number of Deaths');
                app.UIAxes.YTick = unique( round(app.UIAxes.YTick) );
            elseif ((app.ShowDeaths && ~app.ShowCases) && app.ShowDaily)
                plot(app.UIAxes, app.TimePoints, app.AverageDailyDeaths, 'Color', 'blue', 'LineWidth', 2.5);
                ylabel(app.UIAxes, 'Number of Deaths');
                app.UIAxes.YTick = unique( round(app.UIAxes.YTick) );
            elseif (app.ShowBoth && app.ShowCumulative)
                % plot 1
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, app.TimePoints, app.AverageCumulativeCases, 'FaceColor', '#0072BD');
                app.UIAxes.YColor = [ 0 0.4470 0.7410 ];
                ylabel(app.UIAxes, 'Number of Cases');
                ytl = get(app.UIAxes, 'YTick');                 % Get controlling left ticks
                
                hold(app.UIAxes, 'on');
                
                % plot 2
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes, app.TimePoints, app.AverageCumulativeDeaths, 'Color', 'red', 'LineWidth', 1.5);
                app.UIAxes.YColor = [ 0.8500 0.3250 0.0980 ];
                ylabel(app.UIAxes, 'Number of Deaths');
                
                if (max(app.AverageCumulativeDeaths) < 20)
                    app.UIAxes.YTick = unique( round(app.UIAxes.YTick) );
                    app.UIAxes.YLim(1) = 0;
                else
                    ytr = get(app.UIAxes, 'YTick');             % Get original right tick values
                    ytrv = linspace(0, max(ytr), numel(ytl));   % Create new right tick values matching # left ticks
                    app.UIAxes.YTick = ytrv;                    % Set new right ticks
                    app.UIAxes.YTickLabels = round(ytrv);       % Set new right tick labels
                end
                
                hold(app.UIAxes, 'off');
            elseif (app.ShowBoth && app.ShowDaily)
                % plot 1
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, app.TimePoints, app.AverageDailyCases, 'FaceColor', '#000000');
                app.UIAxes.YColor = [ 0 0.4470 0.7410 ];
                ylabel(app.UIAxes, 'Number of Cases');
                ytl = get(app.UIAxes, 'YTick');                 % Get controlling left ticks
                
                hold(app.UIAxes, 'on');
                
                % plot 2
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes, app.TimePoints, app.AverageDailyDeaths, 'Color', 'blue', 'LineWidth', 1.5);
                app.UIAxes.YColor = [ 0.8500 0.3250 0.0980 ];
                ylabel(app.UIAxes, 'Number of Deaths');

                if (max(app.AverageDailyDeaths) < 20)
                    app.UIAxes.YTick = unique(round(app.UIAxes.YTick));
                    app.UIAxes.YLim(1) = 0;
                else
                    ytr = get(app.UIAxes, 'YTick');             % Get original right tick values
                    ytrv = linspace(0, max(ytr), numel(ytl));   % Create new right tick values matching # left ticks
                    app.UIAxes.YTick = ytrv;                    % Set new right ticks
                    app.UIAxes.YTickLabels = round(ytrv);       % Set new right tick labels
                end
                
                hold(app.UIAxes, 'off');
            end
            
            set(app.UIAxes, 'FontName', 'Calibri', 'FontSize', 20);
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'off');
            
            % format date timepoints on x axis
            datetick(app.UIAxes, 'x', 'mm/dd');
            xlim(app.UIAxes, 'manual');
            xlim(app.UIAxes, [ app.StartDate app.EndDate ]);
            %xtickangle(app.UIAxes, 30);
            xlabel(app.UIAxes, 'Dates');
            
            % turn off scientic notations on y axes
            if app.ShowBoth
                app.UIAxes.YAxis(1).Exponent = 0;
                app.UIAxes.YAxis(2).Exponent = 0;
            else
                app.UIAxes.YAxis.Exponent = 0;
            end
            ytickformat(app.UIAxes, '%.0f');
            
            title(app.UIAxes, app.MakeTitle(), 'FontSize', 18);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.GlobalData = covid_data;
            app.LabelDates();
            app.GenerateData(); 
            app.SetDefault();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.CountrySelected = app.CountryListBox.Value;
            app.StateorRegionListBox.Items = [ {'All'}, ...
                                               findobj([app.ListOfCountries{:}], 'Name', app.CountrySelected).ListOfStateNames ];
            app.StateSelected = 'All';
            app.PlotData();
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.StateSelected = app.StateorRegionListBox.Value;
            app.PlotData();
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.ShowBoth = app.BothButton.Value;
            app.PlotData();
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            app.PlotData();
        end

        % Value changed function: averagenumberofdaysSpinner
        function AveragednumberofdaysSliderValueChanged(app, event)
           
            app.AverageOfDay = app.averagenumberofdaysSpinner.Value;
            % plot data
            app.PlotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'My COVID-19 App for Data Visualization';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.StateorRegionLabel = uilabel(app.UIFigure);
            app.StateorRegionLabel.Text = 'State or Region';
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.CountryLabel = uilabel(app.UIFigure);
            app.CountryLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.DatatoplotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.CumulativeButton = uiradiobutton(app.OptionsButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionsButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.averagenumberofdaysSpinnerLabel = uilabel(app.UIFigure);
            app.averagenumberofdaysSpinnerLabel.Text = 'average number of days';
            app.averagenumberofdaysSpinner = uispinner(app.UIFigure);
            app.averagenumberofdaysSpinner.ValueChangedFcn = createCallbackFcn(app, @AveragednumberofdaysSliderValueChanged, true);
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://www.youtube.com/watch?v=dQw4w9WgXcQ';
            app.Hyperlink.Text = 'Dont';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyCovid19App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end